classdef app2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ClearButton                   matlab.ui.control.Button
        VoltScaleSlider_2             matlab.ui.control.Slider
        VoltScaleSlider_2Label        matlab.ui.control.Label
        AnalogSignalButton            matlab.ui.control.Button
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        PeriodEditField               matlab.ui.control.NumericEditField
        PeriodEditFieldLabel          matlab.ui.control.Label
        MagnitudeEditField            matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        AnalogHertzKnob               matlab.ui.control.Knob
        AnalogHertzKnobLabel          matlab.ui.control.Label
        AnalogVoltageKnob             matlab.ui.control.Knob
        AnalogVoltageKnobLabel        matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        ReadSensorandPlotButton       matlab.ui.control.Button
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        DigitalVoltageEditField       matlab.ui.control.NumericEditField
        DigitalVoltageEditFieldLabel  matlab.ui.control.Label
        AnalogVoltageEditField        matlab.ui.control.NumericEditField
        AnalogVoltageEditFieldLabel   matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        MuharremCack19015046merFarukCanbaz20015055BlentKaracanayLabel  matlab.ui.control.Label
        ClearButton_2                 matlab.ui.control.Button
        VoltScaleSlider               matlab.ui.control.Slider
        VoltScaleSliderLabel          matlab.ui.control.Label
        FrequencyEditField_2          matlab.ui.control.NumericEditField
        FrequencyEditField_2Label     matlab.ui.control.Label
        PeriodEditField_2             matlab.ui.control.NumericEditField
        PeriodEditField_2Label        matlab.ui.control.Label
        MagnitudeEditField_2          matlab.ui.control.NumericEditField
        MagnitudeEditField_2Label     matlab.ui.control.Label
        AmplitudeEditField_2          matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label     matlab.ui.control.Label
        DigitalHertzKnob              matlab.ui.control.Knob
        DigitalHertzKnobLabel         matlab.ui.control.Label
        DigitalVoltageKnob            matlab.ui.control.Knob
        DigitalVoltageKnobLabel       matlab.ui.control.Label
        DigitalSignalButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        arduFunc % Description
        aanan = 1;
        counter2Max = 4500;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = 'red';
            xlabel(app.UIAxes3,"Time (s)");  
            ylabel(app.UIAxes3," Voltage (V)");
            xlabel(app.UIAxes2,"Time (s)");
            ylabel(app.UIAxes2," Voltage (V)");

            app.UIAxes2.YLim = [-2 2];
            %app.UIAxes3.YLim = [-2 5];
            
        end

        % Value changed function: AnalogVoltageKnob
        function AnalogVoltageKnobValueChanged(app, event)

global magnitude_raw
global magnitude

            app.AmplitudeEditField.Value = app.AnalogVoltageKnob.Value
            magnitude_raw = app.AnalogVoltageKnob.Value;
            magnitude =  magnitude_raw * 2;
            app.MagnitudeEditField.Value = magnitude;


        end

        % Value changed function: AnalogHertzKnob
        function AnalogHertzKnobValueChanged(app, event)

global freq 
global period
            
            freq = app.AnalogHertzKnob.Value;
            period = (1 / freq);
            app.FrequencyEditField.Value = freq;
            app.PeriodEditField.Value = period;
           
        end

        % Callback function
        function AnalogSwitchValueChanged(app, event)

        end

        % Button pushed function: AnalogSignalButton
        function AnalogSignalButtonPushed(app, event)

            
            peakVltg = app.AnalogVoltageKnob.Value * app.VoltScaleSlider_2.Value;
            xAxisMaxValue = 8562;
            freq2 = app.AnalogHertzKnob.Value;
            
           
            %xAxis = linspace(0,1-1/xAxisMaxValue,xAxisMaxValue);
            %yAxis = peakVltg*sin(pi*2*freq2*xAxis);
            %hold(app.UIAxes,'on');
            %plot(app.UIAxes,xAxis,yAxis);

            xAxis = linspace(0,1-1/xAxisMaxValue,xAxisMaxValue);
            yAxis = peakVltg*sin(pi*2*freq2*xAxis);
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,xAxis,yAxis);
            
          
        end

        % Button pushed function: DigitalSignalButton
        function DigitalSignalButtonPushed(app, event)
            counter2Max = 4500;
            cla(app.UIAxes2); 
            peakVltg2 = app.DigitalVoltageKnob.Value*app.VoltScaleSlider.Value;
            xAxisMaxValue2 = 8562;
            freq3 = app.DigitalHertzKnob.Value;
            xAxis = linspace(0,1-1/xAxisMaxValue2,xAxisMaxValue2);
            %xAxis = linspace(0,counter2Max,4000);
            value1 = square(pi*2*freq3*xAxis);

            yAxis = peakVltg2*value1;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,xAxis,yAxis);
           
        end

        % Value changed function: DigitalVoltageKnob
        function DigitalVoltageKnobValueChanged(app, event)
            value = app.DigitalVoltageKnob.Value;
            app.AmplitudeEditField_2.Value = value;
            app.MagnitudeEditField_2.Value = value*2;
            
        end

        % Value changed function: DigitalHertzKnob
        function DigitalHertzKnobValueChanged(app, event)
            value = app.DigitalHertzKnob.Value;
            
            app.FrequencyEditField_2.Value = value;
            app.PeriodEditField_2.Value = (1 / value);
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Analog Graph Clear
            cla(app.UIAxes3);
            app.aanan = 0;
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
             % Digital Graph Clear
            cla(app.UIAxes2);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.arduFunc = arduino('COM3' ,'Uno');
            app.Lamp.Color = 'green';
            app.ConnectButton.Text = " Connection Established";
        end

        % Callback function
        function ReadsensorValueButtonPushed(app, event)

        end

        % Button pushed function: ReadSensorandPlotButton
        function ReadSensorandPlotButtonPushed(app, event)


            counter2=0;
            
            for counter2=1:app.counter2Max

                analogVoltage(counter2)= readVoltage(app.arduFunc,'A0'); % Measures voltage on pin A0
                app.Lamp_2.Color = 'green';
                pause(0.05);
                digitalVoltage(counter2) = readDigitalPin(app.arduFunc,'D8');
                app.Lamp_3.Color = 'green';
                pause(0.05);

            if analogVoltage(counter2) < 0.3
                digitalVoltage(counter2) = 0;
            end

            app.AnalogVoltageEditField.Value = analogVoltage(counter2);
            app.Lamp_2.Color = 'yellow';
            pause(0.05);
            app.DigitalVoltageEditField.Value = digitalVoltage(counter2);
            app.Lamp_3.Color = 'yellow';
            pause(0.05);
            plot(app.UIAxes3,analogVoltage,'b');
            plot(app.UIAxes2,digitalVoltage,'y');
            
            drawnow;
            end
        end

        % Callback function
        function ClearButton_3Pushed(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes2);
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {680, 680, 680};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {680, 680};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x', 318};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnalogVoltageKnobLabel = uilabel(app.LeftPanel);
            app.AnalogVoltageKnobLabel.Text = 'Analog Voltage';
            app.AnalogVoltageKnob = uiknob(app.LeftPanel, 'continuous');
            app.AnalogVoltageKnob.ValueChangedFcn = createCallbackFcn(app, @AnalogVoltageKnobValueChanged, true);
            app.AnalogHertzKnobLabel = uilabel(app.LeftPanel);
            app.AnalogHertzKnobLabel.Text = 'Analog Hertz';
            app.AnalogHertzKnob = uiknob(app.LeftPanel, 'continuous');
            app.AnalogHertzKnob.ValueChangedFcn = createCallbackFcn(app, @AnalogHertzKnobValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagnitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AnalogSignalButton = uibutton(app.LeftPanel, 'push');
            app.AnalogSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AnalogSignalButtonPushed, true);
            app.AnalogSignalButton.Text = 'Analog Signal';
            app.VoltScaleSlider_2Label = uilabel(app.LeftPanel);
            app.VoltScaleSlider_2Label.Text = 'Volt Scale';
            app.VoltScaleSlider_2 = uislider(app.LeftPanel);
            app.VoltScaleSlider_2.Limits = [0 10];
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Digital Voltage Oscilosscope')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Voltage (V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.CenterPanel);
            title(app.UIAxes3, 'Analog Voltage Ossilosscope')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.Lamp = uilamp(app.CenterPanel);
            app.ConnectButton = uibutton(app.CenterPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AnalogVoltageEditFieldLabel = uilabel(app.CenterPanel);
            app.AnalogVoltageEditFieldLabel.Text = 'Analog Voltage';
            app.AnalogVoltageEditField = uieditfield(app.CenterPanel, 'numeric');
            app.DigitalVoltageEditFieldLabel = uilabel(app.CenterPanel);
            app.DigitalVoltageEditFieldLabel.Text = 'Digital Voltage';
            app.DigitalVoltageEditField = uieditfield(app.CenterPanel, 'numeric');
            app.Lamp_2 = uilamp(app.CenterPanel);
            app.Lamp_3 = uilamp(app.CenterPanel);
            app.ReadSensorandPlotButton = uibutton(app.CenterPanel, 'push');
            app.ReadSensorandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSensorandPlotButtonPushed, true);
            app.ReadSensorandPlotButton.Text = 'Read Sensor and Plot Button';
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = 'indir-removebg-preview.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.DigitalSignalButton = uibutton(app.RightPanel, 'push');
            app.DigitalSignalButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalSignalButtonPushed, true);
            app.DigitalSignalButton.Text = 'Digital Signal';
            app.DigitalVoltageKnobLabel = uilabel(app.RightPanel);
            app.DigitalVoltageKnobLabel.Text = 'Digital Voltage';
            app.DigitalVoltageKnob = uiknob(app.RightPanel, 'continuous');
            app.DigitalVoltageKnob.ValueChangedFcn = createCallbackFcn(app, @DigitalVoltageKnobValueChanged, true);
            app.DigitalHertzKnobLabel = uilabel(app.RightPanel);
            app.DigitalHertzKnobLabel.Text = 'Digital Hertz';
            app.DigitalHertzKnob = uiknob(app.RightPanel, 'continuous');
            app.DigitalHertzKnob.ValueChangedFcn = createCallbackFcn(app, @DigitalHertzKnobValueChanged, true);
            app.AmplitudeEditField_2Label = uilabel(app.RightPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.MagnitudeEditField_2Label = uilabel(app.RightPanel);
            app.MagnitudeEditField_2Label.Text = 'Magnitude';
            app.MagnitudeEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.PeriodEditField_2Label = uilabel(app.RightPanel);
            app.PeriodEditField_2Label.Text = 'Period';
            app.PeriodEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.FrequencyEditField_2Label = uilabel(app.RightPanel);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.VoltScaleSliderLabel = uilabel(app.RightPanel);
            app.VoltScaleSliderLabel.Text = 'Volt Scale';
            app.VoltScaleSlider = uislider(app.RightPanel);
            app.VoltScaleSlider.Limits = [0 10];
            app.ClearButton_2 = uibutton(app.RightPanel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.MuharremCack19015046merFarukCanbaz20015055BlentKaracanayLabel = uilabel(app.RightPanel);
            app.MuharremCack19015046merFarukCanbaz20015055BlentKaracanayLabel.Text = {'Muharrem Cacık 19015046'; 'Ömer Faruk Canbaz 20015055'; 'Bülent Karacançay '; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end